classdef autocor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AutoCorrelationSimulationPanel  matlab.ui.container.Panel
        InputPanel                      matlab.ui.container.Panel
        PlotInputButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        OutputPanel                     matlab.ui.container.Panel
        PlotOutputButton                matlab.ui.control.Button
        TimeLagEditFieldLabel           matlab.ui.control.Label
        TimeLagEditField                matlab.ui.control.NumericEditField
        AxesOut                         matlab.ui.control.UIAxes
        SignalFrequncyInputLabel        matlab.ui.control.Label
        NumberofDataLabel               matlab.ui.control.Label
        FsEditFieldLabel                matlab.ui.control.Label
        FsEditField                     matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        SignalTypeDropDownLabel         matlab.ui.control.Label
        SignalTypeDropDown              matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        SimulationMenuButton            matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotInputButton
        function PlotInputButtonPushed(app, event)
            axes(app.UIAxes);            
            cla;
            
            %x = get(app,app.x.value,'String');
            N = app.NEditField.Value;
            Fs = app.FsEditField.Value;
            
            n=0:N-1;
            t=[1:N]*(1/Fs);
            
            select = app.SignalTypeDropDown.Value;
            
            switch select
                case 'Sinus'
                    y=sin(2*pi*1*n/Fs);
                    plot(app.UIAxes,t,y);
                    grid;
                case 'Cosinus'
                    y=cos(2*pi*1*n/Fs);
                    plot(app.UIAxes,t,y);
                    grid;
                case 'Exponential'
                    y=exp(2*pi*1*n/Fs);
                    plot(app.UIAxes,t,y);
                    grid;   
            end

        end

        % Button pushed function: PlotOutputButton
        function PlotOutputButtonPushed(app, event)
            axes(app.AxesOut);
            cla;
            
            %x = get(app,app.x.value,'String');
            N = app.NEditField.Value;
            Fs = app.FsEditField.Value;
            
            n=0:N-1;
            t=[1:N]*(1/Fs);
            z=[0 1 0 1 0 1 0 1 0 1 0 1 0 1 0 1 0 1];
            select = app.SignalTypeDropDown.Value;
            
            switch select
                case 'Sinus'
                    y=sin(2*pi*1*n/Fs);
                case 'Cosinus'
                    y=cos(2*pi*1*n/Fs);
                case 'Exponential'
                    y=exp(2*pi*1*n/Fs);     
            end
            
            Rxx = xcorr(y);
            plot(app.AxesOut,Rxx);

            grid;
            
            [p q] = max(Rxx);
            lbr_sinyal = length(y);
            time_lag = q-lbr_sinyal;
            
            app.TimeLagEditField.Value=time_lag;
            disp app.TimeLagEditField.Value;
        end

        % Button pushed function: SimulationMenuButton
        function SimulationMenuButtonPushed(app, event)
            simulasimenu;
            closereq();
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AutoCorrelationSimulationPanel = uipanel(app.UIFigure);
            app.AutoCorrelationSimulationPanel.Title = 'Auto-Correlation Simulation';
            app.InputPanel = uipanel(app.AutoCorrelationSimulationPanel);
            app.InputPanel.Title = 'Input Panel';
            app.PlotInputButton = uibutton(app.InputPanel, 'push');
            app.PlotInputButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInputButtonPushed, true);
            app.PlotInputButton.Text = 'Plot Input';
            app.UIAxes = uiaxes(app.InputPanel);
            title(app.UIAxes, 'Input Signal')
            zlabel(app.UIAxes, 'Z')
            app.OutputPanel = uipanel(app.AutoCorrelationSimulationPanel);
            app.OutputPanel.Title = 'Output Panel';
            app.PlotOutputButton = uibutton(app.OutputPanel, 'push');
            app.PlotOutputButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOutputButtonPushed, true);
            app.PlotOutputButton.Text = 'Plot Output';
            app.TimeLagEditFieldLabel = uilabel(app.OutputPanel);
            app.TimeLagEditFieldLabel.Text = 'Time Lag';
            app.TimeLagEditField = uieditfield(app.OutputPanel, 'numeric');
            app.AxesOut = uiaxes(app.OutputPanel);
            title(app.AxesOut, 'Output Signal')
            zlabel(app.AxesOut, 'Z')
            app.SignalFrequncyInputLabel = uilabel(app.AutoCorrelationSimulationPanel);
            app.SignalFrequncyInputLabel.Text = 'Signal Frequncy Input';
            app.NumberofDataLabel = uilabel(app.AutoCorrelationSimulationPanel);
            app.NumberofDataLabel.Text = 'Number of Data';
            app.FsEditFieldLabel = uilabel(app.AutoCorrelationSimulationPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.AutoCorrelationSimulationPanel, 'numeric');
            app.NEditFieldLabel = uilabel(app.AutoCorrelationSimulationPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.AutoCorrelationSimulationPanel, 'numeric');
            app.SignalTypeDropDownLabel = uilabel(app.AutoCorrelationSimulationPanel);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.SignalTypeDropDown = uidropdown(app.AutoCorrelationSimulationPanel);
            app.SignalTypeDropDown.Items = {'-', 'Sinus', 'Cosinus', 'Exponential'};
            app.SignalTypeDropDown.Value = '-';
            app.Label = uilabel(app.AutoCorrelationSimulationPanel);
            app.Label.Text = {'Please input the data in the left of this text, '; 'and then use the ''Plot Input'' button to plot the input signal.'; 'After that use the ''Plot Output'' button to plot the output signal'; 'The point of this simulation is to show '; 'the similarity between two signals'};
            app.SimulationMenuButton = uibutton(app.AutoCorrelationSimulationPanel, 'push');
            app.SimulationMenuButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationMenuButtonPushed, true);
            app.SimulationMenuButton.Text = 'Simulation Menu';
            app.ExitButton = uibutton(app.AutoCorrelationSimulationPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = autocor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end