classdef crosscor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CrossCorrelationSimulationPanel  matlab.ui.container.Panel
        FirstInputPanel            matlab.ui.container.Panel
        PlotInput1Button           matlab.ui.control.Button
        UIAxesInput1               matlab.ui.control.UIAxes
        SecondInputPanel           matlab.ui.container.Panel
        PlotInput2Button           matlab.ui.control.Button
        AxesInput2                 matlab.ui.control.UIAxes
        NumberofDataLabel          matlab.ui.control.Label
        NEditFieldLabel            matlab.ui.control.Label
        NEditField                 matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        stInput2ndInputPanel       matlab.ui.container.Panel
        PlotSumButton              matlab.ui.control.Button
        UIAxesSum                  matlab.ui.control.UIAxes
        OutputPanel_2              matlab.ui.container.Panel
        PlotOutputButton           matlab.ui.control.Button
        TimeLagEditField_2Label    matlab.ui.control.Label
        TimeLagEditField_2         matlab.ui.control.NumericEditField
        AxesOut                    matlab.ui.control.UIAxes
        SignalFrequncyInputLabel   matlab.ui.control.Label
        FsEditFieldLabel           matlab.ui.control.Label
        FsEditField                matlab.ui.control.NumericEditField
        Label2                     matlab.ui.control.Label
        Signal1TypeLabel           matlab.ui.control.Label
        SignalType1                matlab.ui.control.DropDown
        Signal2TypeLabel           matlab.ui.control.Label
        SignalType2                matlab.ui.control.DropDown
        SimulationMenuButton       matlab.ui.control.Button
        ExitButton                 matlab.ui.control.Button
        FirstInputPanel_2          matlab.ui.container.Panel
        PlotInput1Button_2         matlab.ui.control.Button
        UIAxesDiskrit1             matlab.ui.control.UIAxes
        SecondInputPanel_2         matlab.ui.container.Panel
        PlotInput2Button_2         matlab.ui.control.Button
        AxesDiskrit2               matlab.ui.control.UIAxes
        stInput2ndInputPanel_2     matlab.ui.container.Panel
        PlotSumButton_2            matlab.ui.control.Button
        UIAxesSumDiskrit           matlab.ui.control.UIAxes
        OutputPanel_3              matlab.ui.container.Panel
        PlotOutputDiskrit          matlab.ui.control.Button
        CorrelationEditFieldLabel  matlab.ui.control.Label
        CorrelationEditField       matlab.ui.control.NumericEditField
        AxesOutDiskrit             matlab.ui.control.UIAxes
        Label_2                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotInput1Button
        function PlotInput1ButtonPushed(app, event)
            axes(app.UIAxesInput1);
            cla;
            N = app.NEditField.Value;
            Fs = app.FsEditField.Value;
            
            n=0:N-1;
            t=[1:N]*(1/Fs);
            select = app.SignalType1.Value;
            
            switch select
                case 'Sin'
                    y=sin(2*pi*1*n/Fs);
                    plot(app.UIAxesInput1,t,y);
                case 'Cos'
                    y=cos(2*pi*1*n/Fs);
                    plot(app.UIAxesInput1,t,y);
                case 'Exp'
                    y=exp(2*pi*1*n/Fs);
                    plot(app.UIAxesInput1,t,y);
                case 'Rectangular'
                    y = square(t);
                    plot(app.UIAxesInput1,t,y);
                case 'Sawtooth'
                    y=sawtooth(t);
                    plot(app.UIAxesInput1,t,y);
                case 'Triangular'
                    n=0; 
                    T=2; 
                    Ts=T/200; 
                    t=-T/2:Ts:T/2;
                    w=(2*pi)/T; 
                    y = 0; 
                    for n = 0:5 
                    y = y + ((2*n+1)^-2)*(cos((2*n+1)*w*t)); 
                    end 
                    plot(app.UIAxesInput1,t,y);        
            end
        end

        % Button pushed function: PlotInput2Button
        function PlotInput2ButtonPushed(app, event)
            axes(app.AxesInput2);
            cla;
            N = app.NEditField.Value;
            Fs = app.FsEditField.Value;
            
            n=0:N-1;
            t=[1:N]*(1/Fs);
            select = app.SignalType1.Value;
            
            switch select
                case 'Sin'
                    y=sin(2*pi*1*n/Fs);

                case 'Cos'
                    y=cos(2*pi*1*n/Fs);

                case 'Exp'
                    y=exp(2*pi*1*n/Fs);

                case 'Rectangular'
                    y = square(t);
                    
                case 'Sawtooth'
                    y=sawtooth(t);
                    
                case 'Triangular'
                    n=0; 
                    T=2; 
                    Ts=T/200; 
                    t=-T/2:Ts:T/2;
                    w=(2*pi)/T; 
                    y = 0; 
                    for n = 0:5 
                    y = y + ((2*n+1)^-2)*(cos((2*n+1)*w*t)); 
                    end         
            end
            
                pilih = app.SignalType2.Value;
                    switch pilih
                        case 'Sin'
                            a=sin(2*pi*1*n/Fs);
                            
                        case 'Cos'
                            a=cos(2*pi*1*n/Fs);
                            
                        case 'Noise for 1/2 input signal'
                            a=datasample(y/5,N);
                            
                        case 'Noise for 1/4 input signal'
                            a=datasample(y/2,N);
                            
                        case 'Rectangular'
                             a=square(t);
                             
                        case 'Sawtooth'
                             a=sawtooth(t);
                             
                        case 'High Noise'
                            a=datasample(2*y,N);
                    end
                    plot(app.AxesInput2,t,a);
        end

        % Button pushed function: PlotSumButton
        function PlotSumButtonPushed(app, event)
            axes(app.UIAxesSum);
            cla;
            
            N = app.NEditField.Value;
            Fs = app.FsEditField.Value;
            
            n=0:N-1;
            t=[1:N]*(1/Fs);
            select = app.SignalType1.Value;
            
            switch select
                case 'Sin'
                    y=sin(2*pi*1*n/Fs);
                    
                case 'Cos'
                    y=cos(2*pi*1*n/Fs);
                    
                case 'Exp'
                    y=exp(2*pi*1*n/Fs);
                    
                case 'Rectangular'
                    y = square(t);
                    
                case 'Sawtooth'
                    y=sawtooth(t);
                    
                case 'Triangular'
                    n=0; 
                    T=2; 
                    Ts=T/200; 
                    t=-T/2:Ts:T/2;
                    w=(2*pi)/T; 
                    y = 0; 
                    for n = 0:5 
                    y = y + ((2*n+1)^-2)*(cos((2*n+1)*w*t)); 
                    end        
            end
            
                pilih = app.SignalType2.Value;
                    switch pilih
                        case 'Sin'
                            a=sin(2*pi*1*n/Fs);
                            
                        case 'Cos'
                            a=cos(2*pi*1*n/Fs);
                            
                        case 'Noise for 1/2 input signal'
                            a=datasample(y/5,N);
                            
                        case 'Noise for 1/4 input signal'
                            a=datasample(y/2,N);
                            
                        case 'Rectangular'
                             a=square(t);
                             
                        case 'Sawtooth'
                             a=sawtooth(t);
                             
                        case 'High Noise'
                            a=datasample(2*y,N);
                    end
                s=y+a;
                plot(app.UIAxesSum,s);
        end

        % Button pushed function: PlotOutputButton
        function PlotOutputButtonPushed(app, event)
            axes(app.AxesOut);
            cla;
            
            N = app.NEditField.Value;
            Fs = app.FsEditField.Value;
            
            n=0:N-1;
            t=[1:N]*(1/Fs);
            select = app.SignalType1.Value;
            
            switch select
                case 'Sin'
                    y=sin(2*pi*1*n/Fs);
                case 'Cos'
                    y=cos(2*pi*1*n/Fs);
                case 'Exp'
                    y=exp(2*pi*1*n/Fs);
                case 'Rectangular'
                    y = square(t);
                case 'Sawtooth'
                    y=sawtooth(t);
                case 'Triangular'
                    n=0; 
                    T=2; 
                    Ts=T/200; 
                    t=-T/2:Ts:T/2;
                    w=(2*pi)/T; 
                    y = 0; 
                    for n = 0:5 
                    y = y + ((2*n+1)^-2)*(cos((2*n+1)*w*t)); 
                    end        
            end
            
                pilih = app.SignalType2.Value;
                    switch pilih
                        case 'Sin'
                            a=sin(2*pi*1*n/Fs);
                        case 'Cos'
                            a=cos(2*pi*1*n/Fs);
                        case 'Noise for 1/2 input signal'
                            a=datasample(y/5,N);
                        case 'Noise for 1/4 input signal'
                            a=datasample(y/2,N);
                        case 'Rectangular'
                             a=square(t);
                        case 'Sawtooth'
                             a=sawtooth(t);
                        case 'High Noise'
                            a=datasample(2*y,N);
                    end
                
                s=y+a;
                Rxx = xcorr(y,s,'coeff');
                plot (app.AxesOut,Rxx);
                grid;        
        end

        % Button pushed function: SimulationMenuButton
        function SimulationMenuButtonPushed(app, event)
            simulasimenu;
            closereq();
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq();
        end

        % Button pushed function: PlotInput1Button_2
        function PlotInput1Button_2Pushed(app, event)
            axes(app.UIAxesDiskrit1);
            cla;
            
            N = app.NEditField.Value;
            Fs = app.FsEditField.Value;
            
            n=0:N-1;
            t=[1:N]*(1/Fs);
            select = app.SignalType1.Value;
            
            switch select
                case 'Sin'
                    y=sin(2*pi*1*n/Fs);

                case 'Cos'
                    y=cos(2*pi*1*n/Fs);

                case 'Exp'
                    y=exp(2*pi*1*n/Fs);

                case 'Rectangular'
                    y = square(t);
                    
                case 'Sawtooth'
                    y=sawtooth(t);
                    
                case 'Triangular'
                    n=0; 
                    T=2; 
                    Ts=T/200; 
                    t=-T/2:Ts:T/2;
                    w=(2*pi)/T; 
                    y = 0; 
                    for n = 0:5 
                    y = y + ((2*n+1)^-2)*(cos((2*n+1)*w*t)); 
                    end         
            end
                    stem(app.UIAxesDiskrit1,y);        
        end

        % Button down function: UIAxesSumDiskrit
        function UIAxesSumDiskritButtonDown(app, event)
            
        end

        % Button pushed function: PlotInput2Button_2
        function PlotInput2Button_2Pushed(app, event)
            axes(app.AxesDiskrit2);
            cla;
            N = app.NEditField.Value;
            Fs = app.FsEditField.Value;
            
            n=0:N-1;
            t=[1:N]*(1/Fs);
            select = app.SignalType1.Value;
            
            switch select
                case 'Sin'
                    y=sin(2*pi*1*n/Fs);

                case 'Cos'
                    y=cos(2*pi*1*n/Fs);

                case 'Exp'
                    y=exp(2*pi*1*n/Fs);

                case 'Rectangular'
                    y = square(t);
                    
                case 'Sawtooth'
                    y=sawtooth(t);
                    
                case 'Triangular'
                    n=0; 
                    T=2; 
                    Ts=T/200; 
                    t=-T/2:Ts:T/2;
                    w=(2*pi)/T; 
                    y = 0; 
                    for n = 0:5 
                    y = y + ((2*n+1)^-2)*(cos((2*n+1)*w*t)); 
                    end         
            end
            
                pilih = app.SignalType2.Value;
                    switch pilih
                        case 'Sin'
                            a=sin(2*pi*1*n/Fs);
                            
                        case 'Cos'
                            a=cos(2*pi*1*n/Fs);
                            
                        case 'Noise for 1/2 input signal'
                            a=datasample(y/5,N);
                            
                        case 'Noise for 1/4 input signal'
                            a=datasample(y/2,N);
                            
                        case 'Rectangular'
                             a=square(t);
                             
                        case 'Sawtooth'
                             a=sawtooth(t);
                             
                        case 'High Noise'
                            a=datasample(2*y,N);
                    end
                    stem(app.AxesDiskrit2,a);
        end

        % Button pushed function: PlotSumButton_2
        function PlotSumButton_2Pushed(app, event)
            axes(app.UIAxesSumDiskrit);
            cla;
            
            N = app.NEditField.Value;
            Fs = app.FsEditField.Value;
            
            n=0:N-1;
            t=[1:N]*(1/Fs);
            select = app.SignalType1.Value;
            
            switch select
                case 'Sin'
                    y=sin(2*pi*1*n/Fs);
                    
                case 'Cos'
                    y=cos(2*pi*1*n/Fs);
                    
                case 'Exp'
                    y=exp(2*pi*1*n/Fs);
                    
                case 'Rectangular'
                    y = square(t);
                    
                case 'Sawtooth'
                    y=sawtooth(t);
                    
                case 'Triangular'
                    n=0; 
                    T=2; 
                    Ts=T/200; 
                    t=-T/2:Ts:T/2;
                    w=(2*pi)/T; 
                    y = 0; 
                    for n = 0:5 
                    y = y + ((2*n+1)^-2)*(cos((2*n+1)*w*t)); 
                    end        
            end
            
                pilih = app.SignalType2.Value;
                    switch pilih
                        case 'Sin'
                            a=sin(2*pi*1*n/Fs);
                            
                        case 'Cos'
                            a=cos(2*pi*1*n/Fs);
                            
                        case 'Noise for 1/2 input signal'
                            a=datasample(y/5,N);
                            
                        case 'Noise for 1/4 input signal'
                            a=datasample(y/2,N);
                            
                        case 'Rectangular'
                             a=square(t);
                             
                        case 'Sawtooth'
                             a=sawtooth(t);
                             
                        case 'High Noise'
                            a=datasample(2*y,N);
                    end
                s=y+a;
                stem(app.UIAxesSumDiskrit,s);
        end

        % Button pushed function: PlotOutputDiskrit
        function PlotOutputDiskritButtonPushed(app, event)
            axes(app.AxesOutDiskrit);
            cla;
            
            N = app.NEditField.Value;
            Fs = app.FsEditField.Value;
            
            n=0:N-1;
            t=[1:N]*(1/Fs);
            select = app.SignalType1.Value;
            
            switch select
                case 'Sin'
                    y=sin(2*pi*1*n/Fs);
                case 'Cos'
                    y=cos(2*pi*1*n/Fs);
                case 'Exp'
                    y=exp(2*pi*1*n/Fs);
                case 'Rectangular'
                    y = square(t);
                case 'Sawtooth'
                    y=sawtooth(t);
                case 'Triangular'
                    n=0; 
                    T=2; 
                    Ts=T/200; 
                    t=-T/2:Ts:T/2;
                    w=(2*pi)/T; 
                    y = 0; 
                    for n = 0:5 
                    y = y + ((2*n+1)^-2)*(cos((2*n+1)*w*t)); 
                    end        
            end
            
                pilih = app.SignalType2.Value;
                    switch pilih
                        case 'Sin'
                            a=sin(2*pi*1*n/Fs);
                        case 'Cos'
                            a=cos(2*pi*1*n/Fs);
                        case 'Noise for 1/2 input signal'
                            a=datasample(y/5,N);
                        case 'Noise for 1/4 input signal'
                            a=datasample(y/2,N);
                        case 'Rectangular'
                             a=square(t);
                        case 'Sawtooth'
                             a=sawtooth(t);
                        case 'High Noise'
                            a=datasample(2*y,N);
                    end
                
                s=y+a;
                Rxx = xcorr(y,s,'coeff');
                stem(app.AxesOutDiskrit,Rxx);
                grid;

                hasil=y.*a;
                total=sum(hasil);
                hasil_diskrit=total/N;
           
                app.CorrelationEditField.Value =  hasil_diskrit;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CrossCorrelationSimulationPanel = uipanel(app.UIFigure);
            app.CrossCorrelationSimulationPanel.Title = 'Cross Correlation Simulation';
            app.FirstInputPanel = uipanel(app.CrossCorrelationSimulationPanel);
            app.FirstInputPanel.Title = 'First Input';
            app.PlotInput1Button = uibutton(app.FirstInputPanel, 'push');
            app.PlotInput1Button.ButtonPushedFcn = createCallbackFcn(app, @PlotInput1ButtonPushed, true);
            app.PlotInput1Button.Text = 'Plot 1st Input';
            app.UIAxesInput1 = uiaxes(app.FirstInputPanel);
            title(app.UIAxesInput1, '1st Input')
            zlabel(app.UIAxesInput1, 'Z')
            app.SecondInputPanel = uipanel(app.CrossCorrelationSimulationPanel);
            app.SecondInputPanel.Title = 'Second Input';
            app.PlotInput2Button = uibutton(app.SecondInputPanel, 'push');
            app.PlotInput2Button.ButtonPushedFcn = createCallbackFcn(app, @PlotInput2ButtonPushed, true);
            app.PlotInput2Button.Text = 'Plot 2nd Input';
            app.AxesInput2 = uiaxes(app.SecondInputPanel);
            title(app.AxesInput2, '2nd Input')
            zlabel(app.AxesInput2, 'Z')
            app.NumberofDataLabel = uilabel(app.CrossCorrelationSimulationPanel);
            app.NumberofDataLabel.Text = 'Number of Data';
            app.NEditFieldLabel = uilabel(app.CrossCorrelationSimulationPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.CrossCorrelationSimulationPanel, 'numeric');
            app.Label = uilabel(app.CrossCorrelationSimulationPanel);
            app.Label.Text = {'Please input the value of each parameters'; ''};
            app.stInput2ndInputPanel = uipanel(app.CrossCorrelationSimulationPanel);
            app.stInput2ndInputPanel.Title = '1st Input + 2nd Input';
            app.PlotSumButton = uibutton(app.stInput2ndInputPanel, 'push');
            app.PlotSumButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSumButtonPushed, true);
            app.PlotSumButton.Text = 'Plot Sum';
            app.UIAxesSum = uiaxes(app.stInput2ndInputPanel);
            title(app.UIAxesSum, 'Input Signal')
            zlabel(app.UIAxesSum, 'Z')
            app.OutputPanel_2 = uipanel(app.CrossCorrelationSimulationPanel);
            app.OutputPanel_2.Title = 'Output Panel';
            app.PlotOutputButton = uibutton(app.OutputPanel_2, 'push');
            app.PlotOutputButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOutputButtonPushed, true);
            app.PlotOutputButton.Text = 'Plot Output';
            app.TimeLagEditField_2Label = uilabel(app.OutputPanel_2);
            app.TimeLagEditField_2Label.Text = 'Time Lag';
            app.TimeLagEditField_2 = uieditfield(app.OutputPanel_2, 'numeric');
            app.AxesOut = uiaxes(app.OutputPanel_2);
            title(app.AxesOut, 'Output Signal')
            zlabel(app.AxesOut, 'Z')
            app.SignalFrequncyInputLabel = uilabel(app.CrossCorrelationSimulationPanel);
            app.SignalFrequncyInputLabel.Text = 'Signal Frequncy Input';
            app.FsEditFieldLabel = uilabel(app.CrossCorrelationSimulationPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.CrossCorrelationSimulationPanel, 'numeric');
            app.Label2 = uilabel(app.CrossCorrelationSimulationPanel);
            app.Label2.Text = 'The cross-correlation function is a measure of self-similarity between two waveforms f(t) and g(t).';
            app.Signal1TypeLabel = uilabel(app.CrossCorrelationSimulationPanel);
            app.Signal1TypeLabel.Text = 'Signal 1 Type';
            app.SignalType1 = uidropdown(app.CrossCorrelationSimulationPanel);
            app.SignalType1.Items = {'-', 'Sin', 'Cos', 'Exp', 'Rectangular', 'Sawtooth', 'Triangular'};
            app.SignalType1.Value = '-';
            app.Signal2TypeLabel = uilabel(app.CrossCorrelationSimulationPanel);
            app.Signal2TypeLabel.Text = 'Signal 2 Type';
            app.SignalType2 = uidropdown(app.CrossCorrelationSimulationPanel);
            app.SignalType2.Items = {'-', 'Sin', 'Cos', 'Noise for 1/2 input signal', 'Noise for 1/4 input signal', 'Rectangular', 'Sawtooth', 'High Noise'};
            app.SignalType2.Value = '-';
            app.SimulationMenuButton = uibutton(app.UIFigure, 'push');
            app.SimulationMenuButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationMenuButtonPushed, true);
            app.SimulationMenuButton.Text = 'Simulation Menu';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.FirstInputPanel_2 = uipanel(app.UIFigure);
            app.FirstInputPanel_2.Title = 'First Input';
            app.PlotInput1Button_2 = uibutton(app.FirstInputPanel_2, 'push');
            app.PlotInput1Button_2.ButtonPushedFcn = createCallbackFcn(app, @PlotInput1Button_2Pushed, true);
            app.PlotInput1Button_2.Text = 'Plot 1st Input';
            app.UIAxesDiskrit1 = uiaxes(app.FirstInputPanel_2);
            title(app.UIAxesDiskrit1, '1st Input')
            zlabel(app.UIAxesDiskrit1, 'Z')
            app.SecondInputPanel_2 = uipanel(app.UIFigure);
            app.SecondInputPanel_2.Title = 'Second Input';
            app.PlotInput2Button_2 = uibutton(app.SecondInputPanel_2, 'push');
            app.PlotInput2Button_2.ButtonPushedFcn = createCallbackFcn(app, @PlotInput2Button_2Pushed, true);
            app.PlotInput2Button_2.Text = 'Plot 2nd Input';
            app.AxesDiskrit2 = uiaxes(app.SecondInputPanel_2);
            title(app.AxesDiskrit2, '2nd Input')
            zlabel(app.AxesDiskrit2, 'Z')
            app.stInput2ndInputPanel_2 = uipanel(app.UIFigure);
            app.stInput2ndInputPanel_2.Title = '1st Input + 2nd Input';
            app.PlotSumButton_2 = uibutton(app.stInput2ndInputPanel_2, 'push');
            app.PlotSumButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotSumButton_2Pushed, true);
            app.PlotSumButton_2.Text = 'Plot Sum';
            app.UIAxesSumDiskrit = uiaxes(app.stInput2ndInputPanel_2);
            title(app.UIAxesSumDiskrit, 'Input Signal')
            zlabel(app.UIAxesSumDiskrit, 'Z')
            app.UIAxesSumDiskrit.ButtonDownFcn = createCallbackFcn(app, @UIAxesSumDiskritButtonDown, true);
            app.OutputPanel_3 = uipanel(app.UIFigure);
            app.OutputPanel_3.Title = 'Output Panel';
            app.PlotOutputDiskrit = uibutton(app.OutputPanel_3, 'push');
            app.PlotOutputDiskrit.ButtonPushedFcn = createCallbackFcn(app, @PlotOutputDiskritButtonPushed, true);
            app.PlotOutputDiskrit.Text = 'Plot Output';
            app.CorrelationEditFieldLabel = uilabel(app.OutputPanel_3);
            app.CorrelationEditFieldLabel.Text = 'Correlation';
            app.CorrelationEditField = uieditfield(app.OutputPanel_3, 'numeric');
            app.AxesOutDiskrit = uiaxes(app.OutputPanel_3);
            title(app.AxesOutDiskrit, 'Output Signal')
            zlabel(app.AxesOutDiskrit, 'Z')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Discrete Function';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crosscor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end