classdef discrete < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DiscreteCorrelationSimulationPanel  matlab.ui.container.Panel
        Input1Panel                matlab.ui.container.Panel
        PlotInput1Button           matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        Input2Panel                matlab.ui.container.Panel
        PlotInput2Button           matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        NumberofDataLabel          matlab.ui.control.Label
        NEditFieldLabel            matlab.ui.control.Label
        NEditField                 matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        FirstDiscreteSignalLabel   matlab.ui.control.Label
        X1nEditFieldLabel          matlab.ui.control.Label
        Input1EditField            matlab.ui.control.NumericEditField
        SecondDiscreteSignalLabel  matlab.ui.control.Label
        X2nEditFieldLabel          matlab.ui.control.Label
        Input2EditField            matlab.ui.control.NumericEditField
        DiscreteCorrelationLabel   matlab.ui.control.Label
        ResultEditFieldLabel       matlab.ui.control.Label
        ResultEditField            matlab.ui.control.NumericEditField
        CalculateButton            matlab.ui.control.Button
        SimulationMenuButton       matlab.ui.control.Button
        ExitButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotInput1Button
        function PlotInput1ButtonPushed(app, event)
            axes(app.UIAxes);
            cla;
            n = app.NEditField.Value;
            x1 = app.Input1EditField.Value;
            stem(app.UIAxes,x1);
        end

        % Button pushed function: PlotInput2Button
        function PlotInput2ButtonPushed(app, event)
            axes(app.UIAxes2);
            cla;
            n = app.NEditField.Value;
            x2 = app.Input2EditField.Value;
            stem(app.UIAxes2,x2);
        end

        % Value changed function: ResultEditField
        function ResultEditFieldValueChanged(app, event)
            value = app.ResultEditField.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            x1 = app.Input1EditField.Value;
            x2 = app.Input2EditField.Value;
            n = app.NEditField.Value;
            
            kali=x1.*x2;
            
            
            total=sum(kali,n);
            hasil_diskrit=total/n;
           
            app.ResultEditField.Value =  hasil_diskrit;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq()
        end

        % Button pushed function: SimulationMenuButton
        function SimulationMenuButtonPushed(app, event)
            simulasimenu
            closereq()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiscreteCorrelationSimulationPanel = uipanel(app.UIFigure);
            app.DiscreteCorrelationSimulationPanel.Title = 'Discrete Correlation Simulation';
            app.Input1Panel = uipanel(app.DiscreteCorrelationSimulationPanel);
            app.Input1Panel.Title = 'Input 1 Panel';
            app.PlotInput1Button = uibutton(app.Input1Panel, 'push');
            app.PlotInput1Button.ButtonPushedFcn = createCallbackFcn(app, @PlotInput1ButtonPushed, true);
            app.PlotInput1Button.Text = 'Plot Input 1';
            app.UIAxes = uiaxes(app.Input1Panel);
            title(app.UIAxes, 'Input 1 Signal')
            zlabel(app.UIAxes, 'Z')
            app.Input2Panel = uipanel(app.DiscreteCorrelationSimulationPanel);
            app.Input2Panel.Title = 'Input 2 Panel';
            app.PlotInput2Button = uibutton(app.Input2Panel, 'push');
            app.PlotInput2Button.ButtonPushedFcn = createCallbackFcn(app, @PlotInput2ButtonPushed, true);
            app.PlotInput2Button.Text = 'Plot Input 2';
            app.UIAxes2 = uiaxes(app.Input2Panel);
            title(app.UIAxes2, 'Input 2 Signal')
            zlabel(app.UIAxes2, 'Z')
            app.NumberofDataLabel = uilabel(app.DiscreteCorrelationSimulationPanel);
            app.NumberofDataLabel.Text = 'Number of Data';
            app.NEditFieldLabel = uilabel(app.DiscreteCorrelationSimulationPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.DiscreteCorrelationSimulationPanel, 'numeric');
            app.Label = uilabel(app.DiscreteCorrelationSimulationPanel);
            app.Label.Text = {'Please input the data below this text, '; 'and then use the ''Plot Input 1'' button'; 'to plot the first input signal.'; 'After that use the ''Plot Input 2'' button'; 'to plot the second input signal'; 'The point of this simulation is to show '; 'the similarity between two signals in discrete form'};
            app.FirstDiscreteSignalLabel = uilabel(app.DiscreteCorrelationSimulationPanel);
            app.FirstDiscreteSignalLabel.Text = 'First Discrete Signal';
            app.X1nEditFieldLabel = uilabel(app.DiscreteCorrelationSimulationPanel);
            app.X1nEditFieldLabel.Text = 'X1[n]';
            app.Input1EditField = uieditfield(app.DiscreteCorrelationSimulationPanel, 'numeric');
            app.SecondDiscreteSignalLabel = uilabel(app.DiscreteCorrelationSimulationPanel);
            app.SecondDiscreteSignalLabel.Text = 'Second Discrete Signal';
            app.X2nEditFieldLabel = uilabel(app.DiscreteCorrelationSimulationPanel);
            app.X2nEditFieldLabel.Text = 'X2[n]';
            app.Input2EditField = uieditfield(app.DiscreteCorrelationSimulationPanel, 'numeric');
            app.DiscreteCorrelationLabel = uilabel(app.DiscreteCorrelationSimulationPanel);
            app.DiscreteCorrelationLabel.Text = 'Discrete Correlation';
            app.ResultEditFieldLabel = uilabel(app.DiscreteCorrelationSimulationPanel);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.DiscreteCorrelationSimulationPanel, 'numeric');
            app.ResultEditField.ValueChangedFcn = createCallbackFcn(app, @ResultEditFieldValueChanged, true);
            app.CalculateButton = uibutton(app.DiscreteCorrelationSimulationPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SimulationMenuButton = uibutton(app.DiscreteCorrelationSimulationPanel, 'push');
            app.SimulationMenuButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationMenuButtonPushed, true);
            app.SimulationMenuButton.Text = 'Simulation Menu';
            app.ExitButton = uibutton(app.DiscreteCorrelationSimulationPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = discrete

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end